classdef calibApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProCamCalibUIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CaptureTab                      matlab.ui.container.Tab
        CameraControlPanel              matlab.ui.container.Panel
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        ExposureSliderLabel             matlab.ui.control.Label
        ExposureSlider                  matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        GainSlider                      matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        HueSliderLabel                  matlab.ui.control.Label
        HueSlider                       matlab.ui.control.Slider
        SaturationSliderLabel           matlab.ui.control.Label
        SaturationSlider                matlab.ui.control.Slider
        SharpnessSliderLabel            matlab.ui.control.Label
        SharpnessSlider                 matlab.ui.control.Slider
        WhiteBalanceSliderLabel         matlab.ui.control.Label
        WhiteBalanceSlider              matlab.ui.control.Slider
        ResetButton                     matlab.ui.control.Button
        ExposureModeDropDownLabel       matlab.ui.control.Label
        ExposureModeDropDown            matlab.ui.control.DropDown
        WhiteBalanceModeDropDownLabel   matlab.ui.control.Label
        WhiteBalanceModeDropDown        matlab.ui.control.DropDown
        BacklightCompensationDropDownLabel  matlab.ui.control.Label
        BacklightCompensationDropDown   matlab.ui.control.DropDown
        FixtomanualLabel                matlab.ui.control.Label
        FixtooffLabel                   matlab.ui.control.Label
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        CaptureButton                   matlab.ui.control.Button
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        ResolutionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        ProjecionScreenDropDownLabel    matlab.ui.control.Label
        ProjecionScreenDropDown         matlab.ui.control.DropDown
        PreviewPanel                    matlab.ui.container.Panel
        ProUIAxes                       matlab.ui.control.UIAxes
        CamLigthtGridUIAxes             matlab.ui.control.UIAxes
        CamColorGridUIAxes              matlab.ui.control.UIAxes
        BrowseCaptureSaveFolderButton   matlab.ui.control.Button
        DatafolderLabel                 matlab.ui.control.Label
        ProjectorControlPanel           matlab.ui.container.Panel
        ProjectorBrightnessSliderLabel  matlab.ui.control.Label
        ProjectorBrightnessSlider       matlab.ui.control.Slider
        PreviewCamButton                matlab.ui.control.StateButton
        PreviewPrjButton                matlab.ui.control.StateButton
        CalibrationTab                  matlab.ui.container.Tab
        ImagesPanel                     matlab.ui.container.Panel
        SelectAllButton                 matlab.ui.control.Button
        BrowseCalibrationFolderButton   matlab.ui.control.Button
        ImageListBox                    matlab.ui.control.ListBox
        PreviewPanel_2                  matlab.ui.container.Panel
        CalibUIAxes                     matlab.ui.control.UIAxes
        AutoSegButton                   matlab.ui.control.Button
        ManualSegButton                 matlab.ui.control.Button
        PreviewImageTypeButtonGroup     matlab.ui.container.ButtonGroup
        lightGridButton                 matlab.ui.control.RadioButton
        colorGridButton                 matlab.ui.control.RadioButton
        CalibrateButton                 matlab.ui.control.Button
        CheckerboardSizemmEditFieldLabel  matlab.ui.control.Label
        CheckerboardSizemmEditField     matlab.ui.control.NumericEditField
        verboseCheckBox                 matlab.ui.control.CheckBox
        useExistingCamCornersCheckBox   matlab.ui.control.CheckBox
        ReconstructionTab               matlab.ui.container.Tab
    end

    
    properties (Access = private)
        cam % webcam object used to capture image
        camList % a list of connected cameras that are recognized by matlab
        camInfo % all cameras' properties, such as birghtnees, exposure etc.
        camSliderProperties
        camDropDownProperties
        camPreviewImg % screenshot of the camera image
        camPreviewFig % camera preivew figure
        
        imPattern
        screenSize
        prjFigHandel
        prjBrightness = 1.0
        imgCounter = 1 % count how many images captured so far
        
        previewCalibImageType = 'lightGrid' % Description
    end
    
    properties (Access = public)
        % calibration data root and folder name
        dataRoot = pwd
        dataName
        calibInfo % calibration info to be saved to file
        calibOption % calibration options to pass to calibrateGUI
    end


    methods (Access = public)
        
        function initCameras(app)
            %             camList = imaqhwinfo('winvideo'); % windows
            %             camList = imaqhwinfo('macvideo'); % linux
            %             camList = imaqhwinfo('linuxvideo'); % mac
            
            %             for i = numel(camList)
            %                 curDevice = imaqhwinfo(adaptorList.InstalledAdaptors{i});
            %
            %             end
            
            app.camList = webcamlist();
            
            % error if camera not found
            if(numel(app.camList) == 0)
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
            else
                app.CameraDropDown.Items = app.camList;
                
                % controllable camea properties
                app.camSliderProperties = {'Brightness', 'Contrast', 'Exposure', 'Gain', 'Gamma', 'Hue', 'Saturation', 'Sharpness', 'WhiteBalance'};
                app.camDropDownProperties = {'BacklightCompensation', 'ExposureMode', 'WhiteBalanceMode'};
                app.CameraControlPanel.Visible = false;
                % get camera infos, such as supported register values
                %                 getCamInfo(app);
                % display camera names in dropdown list
                %                 app.CameraDropDown.Items = {camList.DeviceInfo.DeviceName};
                %                 app.CameraDropDown.ItemsData = 1:numel(app.CameraDropDown.Items);
                %                 app.CameraDropDown.Items = {camList.DeviceInfo.DeviceName};
                
                %                 app.CameraDropDown.Value = camList{1};
                
                %                 app.ResolutionDropDown.Items =
                
                % start cam
                %                 app.cam = webcam(app.camList{1});
                %                 updateCamResolutionDropDown(app);
                %                 updateCamControlPannel(app, 1);
                %
                %                 % open cam preview window
                %                 app.camPreviewImg = preview(app.cam);
            end
            
        end
        
        %% update the camera resolution dropdown list
        function updateCamResolutionDropDown(app)
            app.ResolutionDropDown.Items = app.cam.AvailableResolutions;
            app.ResolutionDropDown.Value = app.cam.Resolution;
        end
        
        %         function getCamInfo(app, camID)
        
        %             f = waitbar(0, 'Detecting connected webcams...');
        %             for camID = 1:numel(app.camList)
        %                 waitbar(camID/numel(app.camList), f, [num2str(camID), ' webcam discovered...']);
        %                 src = getselectedsource(videoinput('winvideo', camID)); % slow
        %                 app.camInfo{camID} = src.propinfo;
        
        %                 getCamPropertyConstraint(app, camID);
        %             end
        
        %             close(f);
        %         end
        
        function updateCamControlPannel(app, camID)
            initCamSliders(app, camID);
            initCamDropDowns(app, camID);
            resetCamDropDowns(app, camID);
        end
        
        
        function getScreenInfo(app)
            app.screenSize = round(get(0, 'MonitorPositions'));
            numMonitors = size(app.screenSize, 1);
            
            for i=1:numMonitors
                app.ProjecionScreenDropDown.Items{i} = ['[Screen: ', num2str(i), '] ', num2str(app.screenSize(i,3)), 'x', num2str(app.screenSize(i,4))];
            end
            
            app.ProjecionScreenDropDown.ItemsData = 1:numMonitors;
            
            % init projector image
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
            previewImage(app, 'ProUIAxes', app.imPattern);
        end
        
        function figHandle = initPrjWindow(app, screenID, im)
            %             app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            figHandle = figure('MenuBar', 'none', 'ToolBar', 'none');
            imshow(im);
            
            axeHandle = gca;
            set(axeHandle, 'Position', [0 0 1 1])
            set(figHandle, 'Position', app.screenSize(screenID,:))
            set(figHandle, 'WindowState', 'fullscreen')
            
            % speedup projection
            set(gca, 'xlimmode','manual',...
                'ylimmode','manual',...
                'zlimmode','manual',...
                'climmode','manual',...
                'alimmode','manual');
            set(figHandle,'doublebuffer','off');
        end
    end
    
    methods (Access = private)
        
        function initCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                if(any(contains(propertyNames, propertyName)))
                    
                    app.(sliderName).Enable = true;
                    %                     app.(sliderName).Limits = double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Limits = str2double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Value = app.cam.(propertyName);
                    app.(sliderName).Tag = propertyName; % for later use
                else
                    app.(sliderName).Enable = false;
                end
            end
            
        end
        
        function resetCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                
                if(any(contains(propertyNames, propertyName)))
                    
                    app.(sliderName).Value = double(app.camInfo{camID}.(propertyName).DefaultValue);
                    app.cam.(propertyName) = app.(sliderName).Value;
                    
                end
            end
            
        end
        
        function initCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];
                
                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Items = app.camInfo{camID}.(propertyName).ConstraintValue;
                    app.(dropDownName).Tag = propertyName; % for later use
                    
                    % matlab image aquisition toolbox's BacklightCompensation is string, while webcam's is 0 or 1
                    if(strcmp(propertyName, 'BacklightCompensation'))
                        if(app.cam.(propertyName) == 1)
                            app.(dropDownName).Value = 'on';
                        else
                            app.(dropDownName).Value = 'off';
                        end
                    else
                        app.(dropDownName).Value = app.cam.(propertyName);
                    end
                    
                    app.(dropDownName).Enable = true;
                else
                    app.(dropDownName).Enable = false;
                end
            end
            
        end
        
        
        function resetCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];
                
                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Value = app.camInfo{camID}.(propertyName).DefaultValue;
                    
                    try
                        if(strcmp(propertyName, 'BacklightCompensation'))
                            app.cam.(propertyName) =  strcmp(app.(dropDownName).Value, 'on');
                        else
                            app.cam.(propertyName) = app.(dropDownName).Value;
                        end
                    catch e
                        disp(e) % for debug
                    end
                end
            end
        end
        
        
        function projectImage(app, im)
            screenID = app.ProjecionScreenDropDown.Value;
            
            if(isempty(app.prjFigHandel) || ~isvalid(app.prjFigHandel))
                app.prjFigHandel = initPrjWindow(app, screenID, im);
                app.prjFigHandel.DeleteFcn = createCallbackFcn(app, @disablePreviewPrjStateBtn, true); % set preview state button to disable when window is closed
            else
                cla;
                set(app.prjFigHandel, 'WindowState', 'normal');
                imshow(im);
                
                axisHandle = gca;
                set(axisHandle, 'Position', [0 0 1 1]);
                
                set(app.prjFigHandel, 'Position', app.screenSize(screenID,:))
                set(app.prjFigHandel, 'WindowState', 'fullscreen');
                
            end
        end
        
        % preview the input image in a axis specified by its name
        function previewImage(app, axisName, im)
            app.(axisName).PlotBoxAspectRatio = [size(im, 2), size(im, 1), size(im, 1)];
            app.(axisName).XLim = [0 size(im, 2)];
            app.(axisName).YLim = [0 size(im, 1)];
            image(app.(axisName), im);
        end
        
        % use try catch to get camera property constraint values
        function getCamPropertyConstraint(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            
            % sliders
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                
                if(any(contains(propertyNames, propertyName)))
                    try
                        app.cam.(propertyName) = inf;
                    catch e
                        app.camInfo{camID}.(propertyName).ConstraintValue = regexp(e.message,'-?\d*','match');
                    end
                    
                    app.camInfo{camID}.(propertyName).DefaultValue = app.cam.(propertyName);
                end
            end
            
            % dropdowns
            if(any(contains(propertyNames, 'BacklightCompensation')))
                app.camInfo{camID}.BacklightCompensation.ConstraintValue = {'on', 'off'};
                app.camInfo{camID}.BacklightCompensation.DefaultValue = 'off';
            end
            
            if(any(contains(propertyNames, 'ExposureMode')))
                app.camInfo{camID}.ExposureMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.ExposureMode.DefaultValue = 'manual';
            end
            
            if(any(contains(propertyNames, 'WhiteBalanceMode')))
                app.camInfo{camID}.WhiteBalanceMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.WhiteBalanceMode.DefaultValue = 'auto';
            end
            % set ExposureMode to manual and BacklightCompensation to off, since our calibration algorithm requires the lightGrid and colorGrid images have the same background brightness
        end
        
        function saveCalibInfo(app, imLightGrid)
            [~, boardSize, ~] = detectCheckerboardPoints(imLightGrid);
            
            app.calibInfo.camW = size(imLightGrid, 2);
            app.calibInfo.camH = size(imLightGrid, 1);
            
            app.calibInfo.prjW = size(app.imPattern, 2);
            app.calibInfo.prjH = size(app.imPattern, 1);
            
            app.calibInfo.boardSize = boardSize;
            app.calibInfo.sets = 1:app.imgCounter;
            
            %             cv.FileStorage('calib-info.yml', app.calibInfo);
            writeToYML(app, app.calibInfo, fullfile(app.dataRoot, app.dataName, 'calib-info.yml'));
        end
        
        function writeToYML(app, obj, fileName)
            fieldNames = fieldnames(obj);
            f = fopen(fileName, 'w');
            fprintf(f, '%s\n', '%YAML:1.0');
            
            for i = 1: numel(fieldNames)
                fieldName = fieldNames{i};
                data = obj.(fieldName);
                len = length(data);
                if(len > 1)
                    str = [];
                    for j=1:len
                        if(j==1)
                            str = num2str(data(j));
                        else
                            str = [str, ', ' , num2str(data(j))];
                        end
                        
                    end
                    fprintf(f, [fieldName, ': [%s]\n'], str);
                else
                    fprintf(f, [fieldName, ': ', num2str(data),'\n']);
                end
            end
            fclose(f);
        end
        
        function startCamPreview(app)
            app.PreviewCamButton.Value = 1;
            app.CameraControlPanel.Visible = true;
            
            app.camPreviewImg = app.cam.preview;
            f = ancestor(app.camPreviewImg, 'figure');
            f.DeleteFcn = createCallbackFcn(app, @disablePreviewCamStateBtn, true); % set preview state button to disable when window is closed
        end
        
        function setComponentKeyVal(app, fieldName, key, val)
            app.(fieldName).(key) = val;
        end
        
        function disablePreviewCamStateBtn(app, callbackData)
            if(isvalid(app))
                app.PreviewCamButton.Value = 0;
                app.CameraControlPanel.Visible = false;
            end
        end
        
        function disablePreviewPrjStateBtn(app, callbackData)
            if(isvalid(app))
                app.PreviewPrjButton.Value = 0;
                app.ProjectorControlPanel.Visible = false;
            end
        end
        
        
        function updateImageList(app, dataFolder)
            setList = dir(fullfile(dataFolder, '*light*.png'));
            app.ImageListBox.Items = {};
            
            for i=1:numel(setList)
                fileName = setList(i).name;
                app.ImageListBox.Items{i} = ['Set', fileName(end-5:end-4)];
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatafolderLabel.Text = ['Data folder: ', fullfile(app.dataRoot, app.dataName)];
            initCameras(app);
            getScreenInfo(app);
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            camName = app.CameraDropDown.Value;
            camID = find(contains(app.CameraDropDown.Items, camName));
            
            if(app.PreviewCamButton.Value)
                app.cam = webcam(camID);
                
                % also get this cam's property contraint
                if(length(app.camInfo)<camID || isempty(app.camInfo{camID}))
                    getCamPropertyConstraint(app, camID);
                end
                
                app.startCamPreview;
                updateCamControlPannel(app, camID);
                updateCamResolutionDropDown(app);
            end
            
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            screenID = app.ProjecionScreenDropDown.Value;
            
            % project pure black image and capture imLightGrid
            imBlack = zeros(app.screenSize(screenID, 4), app.screenSize(screenID, 3), 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
            projectImage(app, imBlack);
            pause(0.5);
            imLightGrid = app.camPreviewImg.CData;
            previewImage(app, 'CamLigthtGridUIAxes', imLightGrid);
            
            % project color grid and capture imColorGrid
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
            projectImage(app, app.imPattern);
            pause(0.5);
            imColorGrid = app.camPreviewImg.CData;
            previewImage(app, 'CamColorGridUIAxes', imColorGrid);
            
            % delete color grid figure
%             delete(app.prjFigHandel);
%             app.prjFigHandel = [];
            
            % check if there is any existing sets
            dataFolder = fullfile(app.dataRoot, app.dataName);
            updateImageList(app, dataFolder);
            app.imgCounter = numel(app.ImageListBox.Items) + 1; % reset image counter

            % if not sets
            if(app.imgCounter == 0)
                app.imgCounter = 1;
            end       
                
            % save captured images
            imwrite(imLightGrid, fullfile(dataFolder, ['lightGrid', sprintf('%02d', app.imgCounter), '.png']));
            imwrite(imColorGrid, fullfile(dataFolder, ['colorGrid', sprintf('%02d', app.imgCounter), '.png']));
            app.imgCounter = app.imgCounter + 1;
            
            % save calib-info
            saveCalibInfo(app, imLightGrid);
        end

        % Close request function: ProCamCalibUIFigure
        function ProCamCalibUIFigureCloseRequest(app, event)
            delete(app.prjFigHandel);
            delete(app);
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            if(app.PreviewCamButton.Value)
                app.cam.Resolution = app.ResolutionDropDown.Value;
                app.startCamPreview;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            camID = find(contains(app.CameraDropDown.Items, app.CameraDropDown.Value));
            
            resetCamSliders(app, camID);
            resetCamDropDowns(app, camID);
        end

        % Value changed function: BacklightCompensationDropDown, 
        % ExposureModeDropDown, WhiteBalanceModeDropDown
        function camDropDownValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            
            try
                if(strcmp(propertyName, 'BacklightCompensation'))
                    app.cam.BacklightCompensation = strcmp(app.BacklightCompensationDropDown.Value, 'on');
                else
                    app.cam.(propertyName) = app.([propertyName, 'DropDown']).Value;
                end
            catch e
                disp(e) % for debug
            end
        end

        % Value changed function: ProjecionScreenDropDown
        function ProjecionScreenDropDownValueChanged(app, event)
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
            previewImage(app, 'ProUIAxes', app.imPattern);
        end

        % Callback function: BrightnessSlider, ContrastSlider, 
        % ExposureSlider, GainSlider, GammaSlider, HueSlider, 
        % SaturationSlider, SharpnessSlider, WhiteBalanceSlider
        function camSliderValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            
            try
                app.cam.(propertyName) = app.([propertyName, 'Slider']).Value;
            catch e
                disp(e) % for debug
            end
        end

        % Button pushed function: BrowseCaptureSaveFolderButton
        function BrowseCaptureSaveFolderButtonPushed(app, event)
            root = uigetdir(app.dataRoot, 'Select where you want to save the capture data');
            figure(app.ProCamCalibUIFigure);
            if(root)
                app.dataRoot = root;
                app.dataName = ['calibration-', datestr(now,'mm-dd-yy__HH-MM-SS')];
                dataFolder = fullfile(app.dataRoot, app.dataName);
                
                if(~exist(dataFolder, 'dir'))
                    mkdir(dataFolder);
                end
                
                app.DatafolderLabel.Text = ['Data folder: ', dataFolder];
                                    
            end
        end

        % Button pushed function: BrowseCalibrationFolderButton
        function BrowseCalibrationFolderButtonPushed(app, event)
            dataFolder = uigetdir(app.dataRoot, 'Select where calibration data is stored');
            figure(app.ProCamCalibUIFigure);
            if(dataFolder)
                backSlashidx = strfind(dataFolder, '\');
                app.dataRoot = dataFolder(1:backSlashidx(end)-1);
                app.dataName = dataFolder(backSlashidx(end)+1:end);
                
                app.DatafolderLabel.Text = ['Data folder: ', dataFolder];
                
                updateImageList(app, dataFolder);
                
                % also update image preview
                if(~isempty(app.ImageListBox.Items))
                    app.ImageListBox.Value = app.ImageListBox.Items{1};
                    ImageListBoxValueChanged(app, event);
                else
                    app.CalibUIAxes.Children.CData = [];
                end
            end
            %             app.ImageListBox.Items = {imgList.name};
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            imgName = app.ImageListBox.Value;
            im = imread(fullfile(app.dataRoot, app.dataName, [app.previewCalibImageType, imgName{1}(end-1:end), '.png']));
            previewImage(app, 'CalibUIAxes', im);
        end

        % Selection changed function: PreviewImageTypeButtonGroup
        function PreviewImageTypeButtonGroupSelectionChanged(app, event)
            if(~isempty(app.ImageListBox.Value))
                app.previewCalibImageType = app.PreviewImageTypeButtonGroup.SelectedObject.Text;
                ImageListBoxValueChanged(app, event);
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ImageListBox.Value = app.ImageListBox.Items;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            selectedSets = app.ImageListBox.Value;
            selectedIdx = cell2mat(cellfun(@(x) str2num(x(end-1:end)), selectedSets, 'UniformOutput', false));
            
            % check if at least 3 sets are selected
            if(numel(selectedSets) >= 3)
                app.calibOption.sets = selectedIdx;
                app.calibOption.sqSize = app.CheckerboardSizemmEditField.Value;
                app.calibOption.verbose = app.verboseCheckBox.Value;
                app.calibOption.useExistingCamCorners = app.useExistingCamCornersCheckBox.Value;
                
                calibrateGUI(app); % call calibration function
            else
                uialert(app.ProCamCalibUIFigure, 'Need at lease 3 sets to calibrate!', 'Not enough sets');
            end
        end

        % Value changed function: PreviewCamButton
        function PreviewCamButtonValueChanged(app, event)
            camPreviewEnabled = app.PreviewCamButton.Value;
            
            if(camPreviewEnabled)
                camName = app.CameraDropDown.Value;
                
                % init cam if it was not initialized
                if(isempty(app.cam) || ~strcmp(camName, app.cam.Name))
                    camID = find(contains(app.CameraDropDown.Items, camName));
                    app.cam = webcam(camID);
                    
                    % also get this cam's property contraint
                    if(length(app.camInfo)<camID)
                        getCamPropertyConstraint(app, camID);
                    end
                    
                    % update camera control panel and resolution list
                    updateCamControlPannel(app, camID);
                    updateCamResolutionDropDown(app);
                end
                
                app.startCamPreview;
                app.CameraControlPanel.Visible = true;
            else
                app.CameraControlPanel.Visible = false;
                
                if(~isempty(app.cam))
                    app.cam.closePreview;
                end
            end
        end

        % Value changed function: CheckerboardSizemmEditField
        function CheckerboardSizemmEditFieldValueChanged(app, event)
            app.calibOption.sqSize = app.CheckerboardSizemmEditField.Value;
        end

        % Value changed function: verboseCheckBox
        function verboseCheckBoxValueChanged(app, event)
            app.calibOption.verbose = app.verboseCheckBox.Value;
        end

        % Value changed function: useExistingCamCornersCheckBox
        function useExistingCamCornersCheckBoxValueChanged(app, event)
            app.calibOption.useExistingCamCorners = app.useExistingCamCornersCheckBox.Value;
        end

        % Value changed function: PreviewPrjButton
        function PreviewPrjButtonValueChanged(app, event)
           
            if(app.PreviewPrjButton.Value)
                app.ProjectorControlPanel.Visible = true;
                app.ProjectorBrightnessSlider.Enable = true;
                screenID = app.ProjecionScreenDropDown.Value;
                
                % project color grid and capture imColorGrid
                app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
                projectImage(app, app.imPattern);
            else
                app.ProjectorBrightnessSlider.Enable = false;
                if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                    % delete color grid figure
                    delete(app.prjFigHandel);
                    app.prjFigHandel = [];
                end
            end
        end

        % Value changed function: ProjectorBrightnessSlider
        function ProjectorBrightnessSliderValueChanged(app, event)
            app.prjBrightness = app.ProjectorBrightnessSlider.Value;
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
            previewImage(app, 'ProUIAxes', app.imPattern);
            
            if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                app.prjFigHandel.Children.Children.CData = app.imPattern;
            end
        end

        % Button pushed function: AutoSegButton
        function AutoSegButtonPushed(app, event)
            imgName = app.ImageListBox.Value;
            
            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'Not image selected','Error');
                return;
            end
            
            whiteLight = fullfile(app.dataRoot, app.dataName, ['lightGrid', imgName{1}(end-1:end), '.png']);
            colorGrid = fullfile(app.dataRoot, app.dataName, ['colorGrid', imgName{1}(end-1:end), '.png']);
            
            [camCorners, ~, ~] = detectCheckerboardPoints(whiteLight);
            
            if(isempty(camCorners))
                uialert(app.ProCamCalibUIFigure, 'Not checkerboard corners detected, cannot segment','Error');
                return;
            end
            
            [~, imColorGridMasked, imBWBoardMask] = ImgProc.segColorGrid(whiteLight, colorGrid, camCorners, false);
            
            if(strcmp(app.previewCalibImageType, 'lightGrid'))
                previewImage(app, 'CalibUIAxes', cat(3,imBWBoardMask,imBWBoardMask,imBWBoardMask));
            else
                previewImage(app, 'CalibUIAxes', imColorGridMasked);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if(strcmp(selectedTab.Title, 'Calibration'))
                updateImageList(app, fullfile(app.dataRoot, app.dataName));
            end
        end

        % Button pushed function: ManualSegButton
        function ManualSegButtonPushed(app, event)
            imgName = app.ImageListBox.Value;
            
            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'Not image selected','Error');
                return;
            end
            
            whiteLight = fullfile(app.dataRoot, app.dataName, ['lightGrid', imgName{1}(end-1:end), '.png']);
            
            f = figure;
            imshow(imread(whiteLight));
            h = drawfreehand(f.Children);
            imBWBoard = createMask(h);
            imwrite(imBWBoard, fullfile(app.dataRoot, app.dataName, ['bwBoard', imgName{1}(end-1:end), '.png']));
            delete(f);
            
            % invoke auto seg
            AutoSegButtonPushed(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProCamCalibUIFigure = uifigure;
            app.ProCamCalibUIFigure.Name = 'ProCamCalib';
            app.ProCamCalibUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProCamCalibUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ProCamCalibUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.CameraControlPanel = uipanel(app.CaptureTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.ContrastSliderLabel = uilabel(app.CameraControlPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraControlPanel);
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ExposureSliderLabel = uilabel(app.CameraControlPanel);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.ExposureSlider = uislider(app.CameraControlPanel);
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [];
            app.GainSliderLabel = uilabel(app.CameraControlPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.CameraControlPanel);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GainSlider.MinorTicks = [];
            app.GammaSliderLabel = uilabel(app.CameraControlPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.CameraControlPanel);
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [];
            app.HueSliderLabel = uilabel(app.CameraControlPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.CameraControlPanel);
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.HueSlider.MinorTicks = [];
            app.SaturationSliderLabel = uilabel(app.CameraControlPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraControlPanel);
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SaturationSlider.MinorTicks = [];
            app.SharpnessSliderLabel = uilabel(app.CameraControlPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraControlPanel);
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SharpnessSlider.MinorTicks = [];
            app.WhiteBalanceSliderLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceSliderLabel.Text = 'WhiteBalance';
            app.WhiteBalanceSlider = uislider(app.CameraControlPanel);
            app.WhiteBalanceSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.WhiteBalanceSlider.MinorTicks = [];
            app.ResetButton = uibutton(app.CameraControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExposureModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.ExposureModeDropDownLabel.Text = 'ExposureMode';
            app.ExposureModeDropDown = uidropdown(app.CameraControlPanel);
            app.ExposureModeDropDown.Items = {};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.ExposureModeDropDown.Value = {};
            app.WhiteBalanceModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceModeDropDownLabel.Text = 'WhiteBalanceMode';
            app.WhiteBalanceModeDropDown = uidropdown(app.CameraControlPanel);
            app.WhiteBalanceModeDropDown.Items = {};
            app.WhiteBalanceModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.WhiteBalanceModeDropDown.Value = {};
            app.BacklightCompensationDropDownLabel = uilabel(app.CameraControlPanel);
            app.BacklightCompensationDropDownLabel.Text = 'BacklightCompensation';
            app.BacklightCompensationDropDown = uidropdown(app.CameraControlPanel);
            app.BacklightCompensationDropDown.Items = {};
            app.BacklightCompensationDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.BacklightCompensationDropDown.Value = {};
            app.FixtomanualLabel = uilabel(app.CameraControlPanel);
            app.FixtomanualLabel.Text = 'Fix to ''manual'' ';
            app.FixtooffLabel = uilabel(app.CameraControlPanel);
            app.FixtooffLabel.Text = 'Fix to ''off'' ';
            app.BrightnessSliderLabel = uilabel(app.CameraControlPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.CameraControlPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.CaptureButton = uibutton(app.CameraControlPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.CameraDropDownLabel = uilabel(app.CaptureTab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.CaptureTab);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = {};
            app.ResolutionDropDownLabel = uilabel(app.CaptureTab);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.CaptureTab);
            app.ResolutionDropDown.Items = {};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = {};
            app.ProjecionScreenDropDownLabel = uilabel(app.CaptureTab);
            app.ProjecionScreenDropDownLabel.Text = 'Projecion Screen';
            app.ProjecionScreenDropDown = uidropdown(app.CaptureTab);
            app.ProjecionScreenDropDown.Items = {};
            app.ProjecionScreenDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjecionScreenDropDownValueChanged, true);
            app.ProjecionScreenDropDown.Value = {};
            app.PreviewPanel = uipanel(app.CaptureTab);
            app.PreviewPanel.Title = 'Preview';
            app.ProUIAxes = uiaxes(app.PreviewPanel);
            title(app.ProUIAxes, 'Projected color grid')
            app.ProUIAxes.XTick = [];
            app.ProUIAxes.XTickLabel = '';
            app.ProUIAxes.YTick = [];
            app.ProUIAxes.YTickLabel = '';
            app.CamLigthtGridUIAxes = uiaxes(app.PreviewPanel);
            title(app.CamLigthtGridUIAxes, 'Camera light grid image')
            app.CamLigthtGridUIAxes.XTick = [];
            app.CamLigthtGridUIAxes.XTickLabel = {};
            app.CamLigthtGridUIAxes.YTick = [];
            app.CamLigthtGridUIAxes.YTickLabel = {};
            app.CamColorGridUIAxes = uiaxes(app.PreviewPanel);
            title(app.CamColorGridUIAxes, 'Camera color grid image')
            app.CamColorGridUIAxes.XTick = [];
            app.CamColorGridUIAxes.XTickLabel = '';
            app.CamColorGridUIAxes.YTick = [];
            app.CamColorGridUIAxes.YTickLabel = '';
            app.BrowseCaptureSaveFolderButton = uibutton(app.CaptureTab, 'push');
            app.BrowseCaptureSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaptureSaveFolderButtonPushed, true);
            app.BrowseCaptureSaveFolderButton.Text = 'Browse';
            app.DatafolderLabel = uilabel(app.CaptureTab);
            app.DatafolderLabel.Text = 'Data folder: ';
            app.ProjectorControlPanel = uipanel(app.CaptureTab);
            app.ProjectorControlPanel.Title = 'Projector Control';
            app.ProjectorBrightnessSliderLabel = uilabel(app.ProjectorControlPanel);
            app.ProjectorBrightnessSliderLabel.Text = 'ProjectorBrightness';
            app.ProjectorBrightnessSlider = uislider(app.ProjectorControlPanel);
            app.ProjectorBrightnessSlider.Limits = [0 1];
            app.ProjectorBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ProjectorBrightnessSliderValueChanged, true);
            app.ProjectorBrightnessSlider.MinorTicks = [];
            app.ProjectorBrightnessSlider.Value = 1;
            app.PreviewCamButton = uibutton(app.CaptureTab, 'state');
            app.PreviewCamButton.ValueChangedFcn = createCallbackFcn(app, @PreviewCamButtonValueChanged, true);
            app.PreviewCamButton.Text = 'PreviewCam';
            app.PreviewPrjButton = uibutton(app.CaptureTab, 'state');
            app.PreviewPrjButton.ValueChangedFcn = createCallbackFcn(app, @PreviewPrjButtonValueChanged, true);
            app.PreviewPrjButton.Text = 'PreviewPrj';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.ImagesPanel = uipanel(app.CalibrationTab);
            app.ImagesPanel.Title = 'Images';
            app.SelectAllButton = uibutton(app.ImagesPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.BrowseCalibrationFolderButton = uibutton(app.ImagesPanel, 'push');
            app.BrowseCalibrationFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCalibrationFolderButtonPushed, true);
            app.BrowseCalibrationFolderButton.Text = 'Browse';
            app.ImageListBox = uilistbox(app.CalibrationTab);
            app.ImageListBox.Items = {};
            app.ImageListBox.Multiselect = 'on';
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.Value = {};
            app.PreviewPanel_2 = uipanel(app.CalibrationTab);
            app.PreviewPanel_2.Title = 'Preview';
            app.CalibUIAxes = uiaxes(app.PreviewPanel_2);
            title(app.CalibUIAxes, '')
            app.CalibUIAxes.XTick = [];
            app.CalibUIAxes.XTickLabel = '';
            app.CalibUIAxes.YTick = [];
            app.CalibUIAxes.YTickLabel = '';
            app.AutoSegButton = uibutton(app.PreviewPanel_2, 'push');
            app.AutoSegButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSegButtonPushed, true);
            app.AutoSegButton.Text = 'AutoSeg';
            app.ManualSegButton = uibutton(app.PreviewPanel_2, 'push');
            app.ManualSegButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSegButtonPushed, true);
            app.ManualSegButton.Text = 'ManualSeg';
            app.PreviewImageTypeButtonGroup = uibuttongroup(app.CalibrationTab);
            app.PreviewImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreviewImageTypeButtonGroupSelectionChanged, true);
            app.PreviewImageTypeButtonGroup.Title = 'PreviewImageType';
            app.lightGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.lightGridButton.Text = 'lightGrid';
            app.lightGridButton.Value = true;
            app.colorGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.colorGridButton.Text = 'colorGrid';
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.CheckerboardSizemmEditFieldLabel = uilabel(app.CalibrationTab);
            app.CheckerboardSizemmEditFieldLabel.Text = 'Checkerboard Size (mm)';
            app.CheckerboardSizemmEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.CheckerboardSizemmEditField.ValueChangedFcn = createCallbackFcn(app, @CheckerboardSizemmEditFieldValueChanged, true);
            app.CheckerboardSizemmEditField.Value = 22.225;
            app.verboseCheckBox = uicheckbox(app.CalibrationTab);
            app.verboseCheckBox.ValueChangedFcn = createCallbackFcn(app, @verboseCheckBoxValueChanged, true);
            app.verboseCheckBox.Text = 'verbose';
            app.useExistingCamCornersCheckBox = uicheckbox(app.CalibrationTab);
            app.useExistingCamCornersCheckBox.ValueChangedFcn = createCallbackFcn(app, @useExistingCamCornersCheckBoxValueChanged, true);
            app.useExistingCamCornersCheckBox.Text = 'use Existing Cam Corners';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProCamCalibUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProCamCalibUIFigure)
        end
    end
end